classdef A1_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ResetButton               matlab.ui.control.Button
        BaseYEditFieldLabel       matlab.ui.control.Label
        BaseYEditField            matlab.ui.control.NumericEditField
        BaseZEditFieldLabel       matlab.ui.control.Label
        BaseZEditField            matlab.ui.control.NumericEditField
        BaseXEditFieldLabel       matlab.ui.control.Label
        BaseXEditField            matlab.ui.control.NumericEditField
        GoalYEditFieldLabel       matlab.ui.control.Label
        GoalYEditField            matlab.ui.control.NumericEditField
        GoalZEditFieldLabel       matlab.ui.control.Label
        GoalZEditField            matlab.ui.control.NumericEditField
        GoalXEditFieldLabel       matlab.ui.control.Label
        GoalXEditField            matlab.ui.control.NumericEditField
        BrickYEditFieldLabel      matlab.ui.control.Label
        BrickYEditField           matlab.ui.control.NumericEditField
        BrickZEditFieldLabel      matlab.ui.control.Label
        BrickZEditField           matlab.ui.control.NumericEditField
        BrickXEditFieldLabel      matlab.ui.control.Label
        BrickXEditField           matlab.ui.control.NumericEditField
        BasePositionLabel         matlab.ui.control.Label
        BrickPositionLabel        matlab.ui.control.Label
        GoalPositionLabel         matlab.ui.control.Label
        RunButton                 matlab.ui.control.Button
        RobotDropDownLabel        matlab.ui.control.Label
        RobotDropDown             matlab.ui.control.DropDown
        SelectRobotButton         matlab.ui.control.Button
        BaseYawEditFieldLabel     matlab.ui.control.Label
        BaseYawEditField          matlab.ui.control.NumericEditField
        BrickRollEditFieldLabel   matlab.ui.control.Label
        BrickRollEditField        matlab.ui.control.NumericEditField
        BrickPitchEditFieldLabel  matlab.ui.control.Label
        BrickPitchEditField       matlab.ui.control.NumericEditField
        BrickYawEditFieldLabel    matlab.ui.control.Label
        BrickYawEditField         matlab.ui.control.NumericEditField
        GoalRollEditFieldLabel    matlab.ui.control.Label
        GoalRollEditField         matlab.ui.control.NumericEditField
        GoalPitchEditFieldLabel   matlab.ui.control.Label
        GoalPitchEditField        matlab.ui.control.NumericEditField
        GoalYawEditFieldLabel     matlab.ui.control.Label
        GoalYawEditField          matlab.ui.control.NumericEditField
        Label                     matlab.ui.control.Label
        DefaultButton             matlab.ui.control.Button
        SpawnButton               matlab.ui.control.Button
        SpawnButton_2             matlab.ui.control.Button
    end

    
    properties (Access = private)
        baseX; baseY; baseZ; % Base location
        baseYaw; % Base yaw
        brickX; brickY; brickZ; % Brick spawn location
        brickRoll; brickPitch; brickYaw; % brick spawn orientation
        goalX; goalY; goalZ; % Goal location
        goalRoll; goalPitch; goalYaw; % goal orientation
        selectRobot; % 0 = Linear UR3, 1 = Linear UR5
        spawnRobot;
        Robot; % Robot object
        AppBrick; % Brick object
        Goal;
        steps = 75;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear all; clf; clc;
            addpath 'Brick';
            addpath 'MyUR3';
            addpath 'LinearUR3';
            addpath 'Environment'
            hold on;
            
            app.baseX = 0; app.baseY = 0; app.baseZ = 0; app.baseYaw = 0;
            app.brickX = 0; app.brickY = 0; app.brickZ = 0; app.brickRoll = pi; app.brickPitch = 0; app.brickYaw = 0;
            app.goalX = 0; app.goalY = 0; app.goalZ = 0; app.goalRoll = pi; app.goalPitch = 0; app.goalYaw = 0;
            app.Robot = UR3();
            app.selectRobot = 0;
            app.AppBrick = Brick(transl(app.brickX,app.brickY,app.brickZ) * rpy2tr(app.brickRoll,app.brickPitch,app.brickYaw));
            app.Goal = Brick(transl(app.goalX,app.goalY,app.goalZ - 0.04) * rpy2tr(app.brickRoll,app.brickPitch,app.brickYaw));
        end

        % Value changed function: BaseXEditField
        function BaseXEditFieldValueChanged(app, event)
            value = app.BaseXEditField.Value;
            valueDouble = str2double(value);
            app.baseX = valueDouble;
        end

        % Value changed function: BaseYEditField
        function BaseYEditFieldValueChanged(app, event)
            value = app.BaseYEditField.Value;
            valueDouble = str2double(value);
            app.baseY = valueDouble;
        end

        % Value changed function: BaseZEditField
        function BaseZEditFieldValueChanged(app, event)
            value = app.BaseZEditField.Value;
            valueDouble = str2double(value);
            app.baseZ = valueDouble;
        end

        % Value changed function: GoalXEditField
        function GoalXEditFieldValueChanged(app, event)
            value = app.GoalXEditField.Value;
            valueDouble = str2double(value);
            app.goalX = valueDouble;
        end

        % Value changed function: GoalYEditField
        function GoalYEditFieldValueChanged(app, event)
            value = app.GoalYEditField.Value;
            valueDouble = str2double(value);
            app.goalY = valueDouble;
        end

        % Value changed function: GoalZEditField
        function GoalZEditFieldValueChanged(app, event)
            value = app.GoalZEditField.Value;
            valueDouble = str2double(value);
            app.goalZ = valueDouble;
        end

        % Value changed function: BrickXEditField
        function BrickXEditFieldValueChanged(app, event)
            value = app.BrickXEditField.Value;
            valueDouble = str2double(value);
            app.brickX = valueDouble;
        end

        % Value changed function: BrickYEditField
        function BrickYEditFieldValueChanged(app, event)
            value = app.BrickYEditField.Value;
            valueDouble = str2double(value);
            app.brickY = valueDouble;
        end

        % Value changed function: BrickZEditField
        function BrickZEditFieldValueChanged(app, event)
            value = app.BrickZEditField.Value;
            valueDouble = str2double(value);
            app.brickZ = valueDouble;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if (app.selectRobot == 0)
                app.Goal = Brick(transl(app.goalX,app.goalY,app.goalZ - 0.04) * rpy2tr(app.brickRoll,app.brickPitch,app.brickYaw));
                qCurr = app.Robot.model.getpos();
                qCurr = PickAndPlace.UR3PickBrick(app.Robot,qCurr,app.Brick,app.steps);
                qCurr = PickAndPlace.UR3PlaceBrickOnBrick(app.Robot,qCurr,app.Brick,app.Goal,app.steps);
            elseif (app.selectRobot == 1)
                app.Goal = Brick(transl(app.goalX,app.goalY,app.goalZ - 0.04) * rpy2tr(app.brickRoll,app.brickPitch,app.brickYaw));
                qCurr = app.Robot.model.getpos();
                qCurr = PickAndPlace.UR5PickBrick(app.Robot,qCurr,app.Brick,app.steps);
                qCurr = PickAndPlace.UR5PlaceBrickOnBrick(app.Robot,qCurr,app.Brick,app.Goal,app.steps);
            end
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            clear; clf; clc;
            close all;
        end

        % Value changed function: RobotDropDown
        function RobotDropDownValueChanged(app, event)
            value = app.RobotDropDown.Value;
            switch value
                case ('Linear UR3')
                    app.selectRobot = 0;
                case ('Linear UR5')
                    app.selectRobot = 1;
            end
        end

        % Button pushed function: SelectRobotButton
        function SelectRobotButtonPushed(app, event)
            if app.selectRobot == 0
                app.spawnRobot = 0;
            elseif app.selectRobot == 1
                app.spawnRobot = 1;
            end
        end

        % Value changed function: BrickRollEditField
        function BrickRollEditFieldValueChanged(app, event)
            value = app.BrickRollEditField.Value;
            valueDouble = str2double(value);
            app.brickRoll = deg2rad(valueDouble);
        end

        % Value changed function: BrickPitchEditField
        function BrickPitchEditFieldValueChanged(app, event)
            value = app.BrickPitchEditField.Value;
            valueDouble = str2double(value);
            app.brickPitch = deg2rad(valueDouble);
        end

        % Value changed function: BrickYawEditField
        function BrickYawEditFieldValueChanged(app, event)
            value = app.BrickYawEditField.Value;
            valueDouble = str2double(value);
            app.brickYaw = deg2rad(valueDouble);
        end

        % Value changed function: GoalRollEditField
        function GoalRollEditFieldValueChanged(app, event)
            value = app.GoalRollEditField.Value;
            valueDouble = str2double(value);
            app.goalRoll = deg2rad(valueDouble);
        end

        % Value changed function: GoalPitchEditField
        function GoalPitchEditFieldValueChanged(app, event)
            value = app.GoalPitchEditField.Value;
            valueDouble = str2double(value);
            app.goalPitch = deg2rad(valueDouble);
        end

        % Value changed function: GoalYawEditField
        function GoalYawEditFieldValueChanged(app, event)
            value = app.GoalYawEditField.Value;
            valueDouble = str2double(value);
            app.goalYaw = deg2rad(valueDouble);
        end

        % Value changed function: BaseYawEditField
        function BaseYawEditFieldValueChanged(app, event)
            value = app.BaseYawEditField.Value;
            valueDouble = str2double(value);
            app.baseYaw = deg2rad(valueDouble);
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            app.BaseXEditField.Value = -0.4; app.BaseYEditField.Value = -0.3; app.BaseZEditField.Value = 0; 
            app.BaseYawEditField.Value = 0;
            app.BrickXEditField.Value = 0; app.BrickYEditField.Value = 0; app.BrickZEditField.Value = 0;
            app.BrickRollEditField.Value = 180; app.BrickPitchEditField.Value = 0; app.BrickYawEditField.Value = 0;
            app.GoalXEditField.Value = 0; app.GoalYEditField.Value = 0.3; app.GoalZEditField.Value = 0;
            app.GoalRollEditField.Value = 180; app.GoalPitchEditField.Value = 0; app.GoalYawEditField.Value = 0;
            app.selectRobot = 0;
        end

        % Button pushed function: SpawnButton
        function SpawnButtonPushed(app, event)
            if app.spawnRobot == 0
                app.Robot = UR3();
                app.Robot.PlotAndColourRobot();
            elseif app.spawnRobot == 1
                app.Robot = LinearUR5();
                app.Robot.PlotAndColourRobot();
            end
        end

        % Button pushed function: SpawnButton_2
        function SpawnButton_2Pushed(app, event)
            hold on;
            app.AppBrick = Brick(transl(app.brickX,app.brickY,app.brickZ) * rpy2tr(app.brickRoll,app.brickPitch,app.brickYaw));
            app.AppBrick.PlotBrickModel();
            hold off;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.BaseYEditFieldLabel = uilabel(app.UIFigure);
            app.BaseYEditFieldLabel.Text = 'Base Y';
            app.BaseYEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaseYEditField.ValueChangedFcn = createCallbackFcn(app, @BaseYEditFieldValueChanged, true);
            app.BaseZEditFieldLabel = uilabel(app.UIFigure);
            app.BaseZEditFieldLabel.Text = 'Base Z';
            app.BaseZEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaseZEditField.ValueChangedFcn = createCallbackFcn(app, @BaseZEditFieldValueChanged, true);
            app.BaseXEditFieldLabel = uilabel(app.UIFigure);
            app.BaseXEditFieldLabel.Text = 'Base X';
            app.BaseXEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaseXEditField.ValueChangedFcn = createCallbackFcn(app, @BaseXEditFieldValueChanged, true);
            app.GoalYEditFieldLabel = uilabel(app.UIFigure);
            app.GoalYEditFieldLabel.Text = 'Goal Y';
            app.GoalYEditField = uieditfield(app.UIFigure, 'numeric');
            app.GoalYEditField.ValueChangedFcn = createCallbackFcn(app, @GoalYEditFieldValueChanged, true);
            app.GoalZEditFieldLabel = uilabel(app.UIFigure);
            app.GoalZEditFieldLabel.Text = 'Goal Z';
            app.GoalZEditField = uieditfield(app.UIFigure, 'numeric');
            app.GoalZEditField.ValueChangedFcn = createCallbackFcn(app, @GoalZEditFieldValueChanged, true);
            app.GoalXEditFieldLabel = uilabel(app.UIFigure);
            app.GoalXEditFieldLabel.Text = 'Goal X';
            app.GoalXEditField = uieditfield(app.UIFigure, 'numeric');
            app.GoalXEditField.ValueChangedFcn = createCallbackFcn(app, @GoalXEditFieldValueChanged, true);
            app.BrickYEditFieldLabel = uilabel(app.UIFigure);
            app.BrickYEditFieldLabel.Text = 'Brick Y';
            app.BrickYEditField = uieditfield(app.UIFigure, 'numeric');
            app.BrickYEditField.ValueChangedFcn = createCallbackFcn(app, @BrickYEditFieldValueChanged, true);
            app.BrickZEditFieldLabel = uilabel(app.UIFigure);
            app.BrickZEditFieldLabel.Text = 'Brick Z';
            app.BrickZEditField = uieditfield(app.UIFigure, 'numeric');
            app.BrickZEditField.ValueChangedFcn = createCallbackFcn(app, @BrickZEditFieldValueChanged, true);
            app.BrickXEditFieldLabel = uilabel(app.UIFigure);
            app.BrickXEditFieldLabel.Text = 'Brick X';
            app.BrickXEditField = uieditfield(app.UIFigure, 'numeric');
            app.BrickXEditField.ValueChangedFcn = createCallbackFcn(app, @BrickXEditFieldValueChanged, true);
            app.BasePositionLabel = uilabel(app.UIFigure);
            app.BasePositionLabel.Text = 'Base Position';
            app.BrickPositionLabel = uilabel(app.UIFigure);
            app.BrickPositionLabel.Text = 'Brick Position';
            app.GoalPositionLabel = uilabel(app.UIFigure);
            app.GoalPositionLabel.Text = 'Goal Position';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.RobotDropDownLabel = uilabel(app.UIFigure);
            app.RobotDropDownLabel.Text = 'Robot';
            app.RobotDropDown = uidropdown(app.UIFigure);
            app.RobotDropDown.Items = {'Linear UR3', 'Linear UR5'};
            app.RobotDropDown.ValueChangedFcn = createCallbackFcn(app, @RobotDropDownValueChanged, true);
            app.RobotDropDown.Value = 'Linear UR3';
            app.SelectRobotButton = uibutton(app.UIFigure, 'push');
            app.SelectRobotButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRobotButtonPushed, true);
            app.SelectRobotButton.Text = 'Select Robot';
            app.BaseYawEditFieldLabel = uilabel(app.UIFigure);
            app.BaseYawEditFieldLabel.Text = 'Base Yaw';
            app.BaseYawEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaseYawEditField.ValueChangedFcn = createCallbackFcn(app, @BaseYawEditFieldValueChanged, true);
            app.BrickRollEditFieldLabel = uilabel(app.UIFigure);
            app.BrickRollEditFieldLabel.Text = 'Brick Roll';
            app.BrickRollEditField = uieditfield(app.UIFigure, 'numeric');
            app.BrickRollEditField.ValueChangedFcn = createCallbackFcn(app, @BrickRollEditFieldValueChanged, true);
            app.BrickPitchEditFieldLabel = uilabel(app.UIFigure);
            app.BrickPitchEditFieldLabel.Text = 'Brick Pitch';
            app.BrickPitchEditField = uieditfield(app.UIFigure, 'numeric');
            app.BrickPitchEditField.ValueChangedFcn = createCallbackFcn(app, @BrickPitchEditFieldValueChanged, true);
            app.BrickYawEditFieldLabel = uilabel(app.UIFigure);
            app.BrickYawEditFieldLabel.Text = 'Brick Yaw';
            app.BrickYawEditField = uieditfield(app.UIFigure, 'numeric');
            app.BrickYawEditField.ValueChangedFcn = createCallbackFcn(app, @BrickYawEditFieldValueChanged, true);
            app.GoalRollEditFieldLabel = uilabel(app.UIFigure);
            app.GoalRollEditFieldLabel.Text = 'Goal Roll';
            app.GoalRollEditField = uieditfield(app.UIFigure, 'numeric');
            app.GoalRollEditField.ValueChangedFcn = createCallbackFcn(app, @GoalRollEditFieldValueChanged, true);
            app.GoalPitchEditFieldLabel = uilabel(app.UIFigure);
            app.GoalPitchEditFieldLabel.Text = 'Goal Pitch';
            app.GoalPitchEditField = uieditfield(app.UIFigure, 'numeric');
            app.GoalPitchEditField.ValueChangedFcn = createCallbackFcn(app, @GoalPitchEditFieldValueChanged, true);
            app.GoalYawEditFieldLabel = uilabel(app.UIFigure);
            app.GoalYawEditFieldLabel.Text = 'Goal Yaw';
            app.GoalYawEditField = uieditfield(app.UIFigure, 'numeric');
            app.GoalYawEditField.ValueChangedFcn = createCallbackFcn(app, @GoalYawEditFieldValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Brick R should always be 180 to ensure the end effector'; 'direction heading down towards the brick'};
            app.DefaultButton = uibutton(app.UIFigure, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.SpawnButton = uibutton(app.UIFigure, 'push');
            app.SpawnButton.ButtonPushedFcn = createCallbackFcn(app, @SpawnButtonPushed, true);
            app.SpawnButton.Text = 'Spawn';
            app.SpawnButton_2 = uibutton(app.UIFigure, 'push');
            app.SpawnButton_2.ButtonPushedFcn = createCallbackFcn(app, @SpawnButton_2Pushed, true);
            app.SpawnButton_2.Text = 'Spawn';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = A1_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end